classdef GeNEsIS_create < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        UIAxesPositions            matlab.ui.control.UIAxes
        NumerosityLabel            matlab.ui.control.Label
        EditFieldNumerosity        matlab.ui.control.NumericEditField
        InterDistance              matlab.ui.control.Label
        ConvexHull                 matlab.ui.control.Label
        ComputeButton              matlab.ui.control.Button
        visualizeSlider            matlab.ui.control.Slider
        generationsEditFieldLabel  matlab.ui.control.Label
        generationsEditField       matlab.ui.control.NumericEditField
        ProceedButton              matlab.ui.control.Button
        Generation                 matlab.ui.control.Label
        ArenaradiusEditFieldLabel  matlab.ui.control.Label
        ArenaradiusEditField       matlab.ui.control.NumericEditField
        UpdateLabel                matlab.ui.control.Label
        BalanceButtonGroup         matlab.ui.container.ButtonGroup
        NothingButton              matlab.ui.control.RadioButton
        InterdistanceButton        matlab.ui.control.RadioButton
        ConvexhullButton           matlab.ui.control.RadioButton
        DensityButton              matlab.ui.control.RadioButton
        EditFieldLabel             matlab.ui.control.Label
        EditField                  matlab.ui.control.NumericEditField
        AcceptederrorLabel         matlab.ui.control.Label
        AcceptederrorEditField     matlab.ui.control.NumericEditField
        ConvexHull_2               matlab.ui.control.Label
        UnitsestimationPanel       matlab.ui.container.Panel
        ArenarcmEditFieldLabel     matlab.ui.control.Label
        ArenarcmEditField          matlab.ui.control.NumericEditField
        pixelLabel                 matlab.ui.control.Label
        mmLabel                    matlab.ui.control.Label
        arenashapeSwitch           matlab.ui.control.Switch
        GeNEsIScreateLabel         matlab.ui.control.Label
        iButton                    matlab.ui.control.Button
    end

    
    properties (Access = public)
    % external frame defined as:
        % arena.s (arena shape, 'c'=circle, 's'=square)
        % arena.r (dimention, radius for circle; half-side for square)
        arena;
    % stimulus defined as: 
        % ball.s (stimulus shape, 'c'=circle, 's'=square, 'd'=diamond, 't'=triangle); 
        % ball.r (characteristic dimention, radius for circle, side size for square triangle and diamond);
        % ball.X (x,y coordinates of the baricenter);
        %ball.r and .X are cells of {#generations} for the different set generations
        ball;
    % numerosity (number of stimuli -shapes- for generation)
        nBalls;
    % number of random point set generations
        nGenerations;
    end
    
    methods (Access = private)

    % generation of points with fixed convex hull    
        function stop=computeCHPositions(app, generation)
            stop=0; % parameter to check computation status
            CHref=round(app.EditField.Value,2); %fixed convex hull to match
            err = round(CHref*app.AcceptederrorEditField.Value/100,2); % accepted error
            CH=0;
            tic;
            while CH < CHref - err || CH > CHref + err
                cont=1; % parameter to continue generations until the desired number is reached
                if generation==1
                    t=toc;
                    if t>120 % check to close computation if it takes too much time to find first combination (> 2min)
                        stop=1;
                        return;
                    end
                end
                while cont~=0
                        X=2*app.arena.r*rand(app.nBalls, 2)-app.arena.r; % (x,y) nBalls-points random generation between -r and r (zero centered)
                        app.ball.X{generation}=X;
                        for j=1:app.nBalls
                            d=[0,0;app.ball.X{generation}(j,:)];
                            if strcmp(app.arenashapeSwitch.Value, 'Circle') == 1 && pdist(d)>=app.arena.r % remove generation if points outside circle of radius r
                                cont=cont+1;
                                break
                            else
                                if j==app.nBalls
                                    cont=0; % if all points are inside count=0 makes while loop to end and pass to next generation
                                end
                            end
                        end
                end
                [~,av] = convhull(app.ball.X{generation});
                CH = round(av,2);
            end
            return;
        end
        
   % generation of points with fixed density (=convex hull/number of points)      
        function stop=computeDPositions(app, generation)
            stop=0;% parameter to check computation status
            Dref=round(app.EditField.Value,2); % fixed density to match
            err = round(Dref*app.AcceptederrorEditField.Value/100,2); % accepted error
            D=0;
            tic;
            while D < Dref-err || D > Dref+err
                cont=1; % parameter to continue generations until the desired number is reached
                if generation==1
                    t=toc;
                    if t>120 % check to close computation if it takes too much time to find first combination (> 2min)
                        stop=1;
                        return;
                    end
                end
                while cont~=0   
                        X=2*app.arena.r*rand(app.nBalls, 2)-app.arena.r; % (x,y) nBalls-points random generation between -r and r (zero centered)
                        app.ball.X{generation}=X;
                        for j=1:app.nBalls
                            d=[0,0;app.ball.X{generation}(j,:)];
                            if strcmp(app.arenashapeSwitch.Value, 'Circle') == 1 && pdist(d)>=app.arena.r % remove generation if points outside circle of radius r
                                cont=cont+1;
                                break
                            else
                                if j==app.nBalls
                                    cont=0;
                                end
                            end
                        end
                end
                [~,av] = convhull(app.ball.X{generation});
                D = round(app.nBalls/av,2);
            end
            return;
        end
        
    % generation of points with fixed mean inter distance  
        function stop=computeIDPositions(app, generation)
            stop=0;% parameter to check computation status
            IDref=round(app.EditField.Value,2); % fixed mean inter distance to match
            err = round(IDref*app.AcceptederrorEditField.Value/100,2); % accepted error
            ID=0;
            tic;
            while ID < IDref - err || ID > IDref - err
                cont=1; % parameter to continue generations until the desired number is reached
                if generation==1
                    t=toc;
                    if t>120 % check to close computation if it takes too much time to find first combination (> 2min)
                        stop=1;
                        return;
                    end
                end
                while cont~=0   
                        X=2*app.arena.r*rand(app.nBalls, 2)-app.arena.r; % (x,y) nBalls-points random generation between -r and r (zero centered)
                        app.ball.X{generation}=X;
                        for j=1:app.nBalls
                            d=[0,0;app.ball.X{generation}(j,:)];
                            if strcmp(app.arenashapeSwitch.Value, 'Circle') == 1 && pdist(d)>=app.arena.r % remove generation if points outside circle of radius r
                                cont=cont+1;
                                break
                            else
                                if j==app.nBalls
                                    cont=0;
                                end
                            end
                        end
                end
                ID = round(mean(pdist(app.ball.X{generation})),2);
            end
            return;
        end
        
     % random generation of points
        function computeRndPositions(app, generation)
            cont=1; % parameter to continue generations until the desired number is reached
            while cont~=0   
                    X=2*app.arena.r*rand(app.nBalls, 2)-app.arena.r; % (x,y) nBalls-points random generation between -r and r (zero centered)
                    app.ball.X{generation}=X;
                    for j=1:app.nBalls
                        d=[0,0;app.ball.X{generation}(j,:)];
                        if strcmp(app.arenashapeSwitch.Value, 'Circle') == 1 && pdist(d)>=app.arena.r % remove generation if points outside circle of radius r
                            cont=cont+1;
                            break
                        else
                            if j==app.nBalls
                                cont=0;
                            end
                        end
                    end
            end
        end
        
    % plot arena and shapes generated, reporting relative geometric parameters
        function plotPositions(app, generation)
            cla(app.UIAxesPositions)
            hold(app.UIAxesPositions, 'on')
            if strcmp(app.arenashapeSwitch.Value, 'Circle') == 1
                % arena circle
                th = 0:pi/50:2*pi;
                x_circle = app.arena.r* cos(th);
                y_circle = app.arena.r * sin(th);
                plot(app.UIAxesPositions, x_circle, y_circle, 'Color', 'k');
            end
            % dots inside arena
            if app.nBalls>1
                D = pdist(app.ball.X{generation});
                app.InterDistance.Text=['Inter distance: ' num2str(round(mean(D),2))];
                if app.nBalls>2
                    [~,av] = convhull(app.ball.X{generation});
                    app.ConvexHull.Text=['Convex hull: ' num2str(round(av,2))];
                    app.ConvexHull_2.Text=['Density: ' num2str(round(app.nBalls/av,2))];
                else % for 2 points CH, D not defined
                    app.ConvexHull.Text='Convex hull: 0';
                    app.ConvexHull_2.Text='Density: 0';
                end
            else % for one point CH, D and ID not defined
                app.ConvexHull.Text='Convex hull: 0';
                app.InterDistance.Text='Inter distance: 0';
                app.ConvexHull_2.Text='Density: 0';
            end
            plot(app.UIAxesPositions,app.ball.X{generation}(:,1),app.ball.X{generation}(:,2),'b*');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.UIFigure.Name='GeNEsIS - Step 1: create balanced positions';
            app.arena.r=app.ArenaradiusEditField.Value;
            app.nBalls=app.EditFieldNumerosity.Value;
            app.nGenerations=app.generationsEditField.Value;
            app.visualizeSlider.Limits=[1 app.nGenerations];
            app.visualizeSlider.Enable='off';
            app.Generation.Text=[ '1/' num2str(app.nGenerations)];
            app.Generation.Enable='off';
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [1 0 0];
            app.ProceedButton.Enable='off';
        end

        % Value changed function: ArenaradiusEditField
        function ArenaradiusEditFieldValueChanged(app, event)
            app.arena.r = app.ArenaradiusEditField.Value; 
            app.ProceedButton.BackgroundColor = [0.851 0.3255 0.098];
            app.ComputeButton.Enable='on';
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [1 0 0];
            waitfor(msgbox('Big values of arena radius can make the program slow down or abort if using a circular arena! If you want to use a big numerosity and thus increasing the arena size, if possible choose a squared arena' , 'Attention'));
            drawnow;
            if isempty(app.ball)
                app.visualizeSlider.Enable='off';
                app.Generation.Enable='off';
                app.ProceedButton.Enable='off';
            end
            figure(app.UIFigure)
        end

        % Value changed function: generationsEditField
        function generationsEditFieldValueChanged(app, event)
            app.nGenerations = app.generationsEditField.Value;
            app.visualizeSlider.Limits=[1 app.nGenerations];
            app.ProceedButton.BackgroundColor = [0.851 0.3255 0.098];
            app.ComputeButton.Enable='on';
            app.UpdateLabel.Text='Not update!';
            app.UpdateLabel.FontColor = [1 0 0];
            app.visualizeSlider.Enable='off';
            app.Generation.Enable='off';
        end

        % Value changed function: EditFieldNumerosity
        function EditFieldNumerosityValueChanged(app, event)
            app.nBalls = app.EditFieldNumerosity.Value;
            app.InterdistanceButton.Enable='on';
            app.ConvexhullButton.Enable='on';
            if app.nBalls==1 % for one point CH, D and ID not defined, disabled
                app.NothingButton.Value = true;
                app.InterdistanceButton.Enable='off';
                app.ConvexhullButton.Enable='off';
            elseif app.nBalls==2 % for two points CH, D not defined, disabled
                app.NothingButton.Value = true;
                app.ConvexhullButton.Enable='off';
            end
            app.ProceedButton.BackgroundColor = [0.851 0.3255 0.098];
            app.ComputeButton.Enable='on'; 
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [1 0 0];
            app.visualizeSlider.Enable='off';
            app.Generation.Enable='off';
        end

        % Selection changed function: BalanceButtonGroup
        function BalanceButtonGroupSelectionChanged(app, event)
            selectedButton = app.BalanceButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Inter distance'
                    app.EditField.Enable='on';
                    app.EditFieldLabel.Enable='on';
                    app.EditFieldLabel.Text='Fixed ID:';
                    app.ComputeButton.Enable='off';
                    waitfor(msgbox('Inter-distance is the mean of all points distances. To find the best ID parameter you can first check the typical values running a fast non-balanced trial; out-of-range parameters can make the program slow down or abort!', 'Info ID'));
                case 'Convex hull'
                    app.EditField.Enable='on';
                    app.EditFieldLabel.Enable='on';
                    app.EditFieldLabel.Text='Fixed CH:';
                    app.ComputeButton.Enable='off';
                    waitfor(msgbox('Convex hull is the total area occupied by the points disposition. To find the best CH parameter you can first check the typical values running a fast non-balanced trial; out-of-range parameters can make the program slow down or abort!', 'Info CH'));
                case 'Nothing'
                    app.EditField.Enable='off';
                    app.EditFieldLabel.Enable='off';
                    app.EditFieldLabel.Text='--';
                    app.ComputeButton.Enable='on';
                case 'Density'
                    app.EditField.Enable='on';
                    app.EditFieldLabel.Enable='on';
                    app.EditFieldLabel.Text='Fixed D:';
                    app.ComputeButton.Enable='off';
                    waitfor(msgbox('Density is defined as number of elements divided by occupied area (D=n/CH). To find the best D parameter you can first check the typical values running a fast non-balanced trial; out-of-range parameters can make the program slow down or abort!', 'Info Density'));
            end
            app.ProceedButton.BackgroundColor = [0.851 0.3255 0.098];
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [1 0 0];
            drawnow;
            if isempty(app.ball)
                app.visualizeSlider.Enable='off';
                app.Generation.Enable='off';
                app.ProceedButton.Enable='off';
            end
            figure(app.UIFigure)
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            app.ProceedButton.BackgroundColor = [0.851 0.3255 0.098];
            app.ComputeButton.Enable='on';
            app.UpdateLabel.Text='Not updated!';
            app.UpdateLabel.FontColor = [1 0 0];
        end

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            answer='Continue';
            if app.InterdistanceButton.Value==1||app.ConvexhullButton.Value==1
                answer = questdlg('The operation could take a long time.','Info', 'Continue', 'Abort', 'Continue');
            end
            switch answer
                case 'Continue'
                    f = waitbar(0,'Please wait while generating positions. Process at 0%');
                    stop=0; % parameter to check computation status (and abort if it takes too much time)
                    disp(['Start computation: ' datestr(now, 'dd/mm/yy-HH:MM')])
                    for i=1:app.nGenerations
                        if stop==1 % abort if generation computation takes too much time (>2 min)
                            disp('Aborted: parameters too restrictive')
                            cla(app.UIAxesPositions);
                            app.ProceedButton.Enable='off';
                            break;
                        end
                        switch app.BalanceButtonGroup.SelectedObject.Text
                            case 'Inter distance'
                                stop=computeIDPositions(app, i);
                            case 'Convex hull'
                                stop=computeCHPositions(app, i);
                            case 'Nothing'
                                computeRndPositions(app, i)
                            case 'Density'
                                stop=computeDPositions(app, i);
                        end
                        waitbar(i/app.nGenerations,f,['Generation ' num2str(i) ' of ' num2str(app.nGenerations) '. Process at ' num2str(round(i/app.nGenerations*100)) '%']);
                        if ~ishghandle(f) % abort if user closes progress bar
                           disp('Stopped by user!');
                           app.nGenerations=i;
                           app.generationsEditField.Value=app.nGenerations;
                           app.visualizeSlider.Limits=[1 app.nGenerations];
                           break
                        end
                    end
                    if ishghandle(f) % if progress bar was not closed by user
                        close(f)
                    end
                    app.visualizeSlider.Value=1;
                    if app.nGenerations>1
                        app.visualizeSlider.Enable='on';
                    end
                    app.Generation.Text=[ '1/' num2str(app.nGenerations)];
                    app.Generation.Enable='on';
                    plotPositions(app, 1)
                    app.ProceedButton.BackgroundColor = [0 1 0];
                    app.visualizeSlider.Enable='on';
                    app.UpdateLabel.Text='Updated';
                    app.UpdateLabel.FontColor = [0 1 0];
                    app.ProceedButton.Enable='on';

                    if stop==0
                        disp(['End computation: ' datestr(now, 'dd/mm/yy-HH:MM')])
                    end 
                    figure(app.UIFigure)
                case 'Abort'
                    drawnow;
                    app.visualizeSlider.Enable='off';
                    app.Generation.Enable='off';
                    app.ProceedButton.Enable='off';
                    figure(app.UIFigure)
            end
        end

        % Value changing function: visualizeSlider
        function visualizeSliderValueChanging(app, event)
            changingValue = round(event.Value);
            app.Generation.Text=[num2str(changingValue) '/' num2str(app.nGenerations)];
            plotPositions(app, changingValue)
            app.visualizeSlider.Value=changingValue;
        end

        % Button pushed function: ProceedButton
        function ProceedButtonPushed(app, event)
            shapesGenesis(app)
        end

        % Value changed function: ArenarcmEditField
        function ArenarcmEditFieldValueChanged(app, event)
            value = app.ArenarcmEditField.Value;
            if value==0
                app.pixelLabel.Text='1 pixel = ---';
                app.mmLabel.Text='1 mm = ---';
            else
                pixelDim=value/app.arena.r;
                app.pixelLabel.Text=['1 pixel = ', num2str(pixelDim), ' cm'];
                app.mmLabel.Text=['1 mm = ', num2str(10/pixelDim), ' px'];
            end
        end

        % Button pushed function: iButton
        function iButtonPushed(app, event)
            winopen('./Tutorial/GeNEsIS_create_tutorial.pdf')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxesPositions = uiaxes(app.UIFigure);
            title(app.UIAxesPositions, '')
            xlabel(app.UIAxesPositions, '')
            ylabel(app.UIAxesPositions, '')
            app.NumerosityLabel = uilabel(app.UIFigure);
            app.NumerosityLabel.Text = 'Numerosity';
            app.EditFieldNumerosity = uieditfield(app.UIFigure, 'numeric');
            app.EditFieldNumerosity.Limits = [1 Inf];
            app.EditFieldNumerosity.ValueChangedFcn = createCallbackFcn(app, @EditFieldNumerosityValueChanged, true);
            app.EditFieldNumerosity.Value = 3;
            app.InterDistance = uilabel(app.UIFigure);
            app.InterDistance.Text = 'Inter distance';
            app.ConvexHull = uilabel(app.UIFigure);
            app.ConvexHull.Text = 'Convex hull';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.visualizeSlider = uislider(app.UIFigure);
            app.visualizeSlider.Limits = [1 100000];
            app.visualizeSlider.MajorTicks = [];
            app.visualizeSlider.MajorTickLabels = {''};
            app.visualizeSlider.ValueChangingFcn = createCallbackFcn(app, @visualizeSliderValueChanging, true);
            app.visualizeSlider.MinorTicks = [];
            app.visualizeSlider.Value = 1;
            app.generationsEditFieldLabel = uilabel(app.UIFigure);
            app.generationsEditFieldLabel.Text = '# generations';
            app.generationsEditField = uieditfield(app.UIFigure, 'numeric');
            app.generationsEditField.Limits = [1 Inf];
            app.generationsEditField.ValueChangedFcn = createCallbackFcn(app, @generationsEditFieldValueChanged, true);
            app.generationsEditField.Value = 10;
            app.ProceedButton = uibutton(app.UIFigure, 'push');
            app.ProceedButton.ButtonPushedFcn = createCallbackFcn(app, @ProceedButtonPushed, true);
            app.ProceedButton.Enable = 'off';
            app.ProceedButton.Text = 'Proceed >>';
            app.Generation = uilabel(app.UIFigure);
            app.ArenaradiusEditFieldLabel = uilabel(app.UIFigure);
            app.ArenaradiusEditFieldLabel.Text = 'Arena radius';
            app.ArenaradiusEditField = uieditfield(app.UIFigure, 'numeric');
            app.ArenaradiusEditField.Limits = [4 300];
            app.ArenaradiusEditField.ValueChangedFcn = createCallbackFcn(app, @ArenaradiusEditFieldValueChanged, true);
            app.ArenaradiusEditField.Value = 10;
            app.UpdateLabel = uilabel(app.UIFigure);
            app.UpdateLabel.Text = 'Not updated!';
            app.BalanceButtonGroup = uibuttongroup(app.UIFigure);
            app.BalanceButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BalanceButtonGroupSelectionChanged, true);
            app.BalanceButtonGroup.Title = 'Balance:';
            app.NothingButton = uiradiobutton(app.BalanceButtonGroup);
            app.NothingButton.Text = 'Nothing';
            app.NothingButton.Value = true;
            app.InterdistanceButton = uiradiobutton(app.BalanceButtonGroup);
            app.InterdistanceButton.Text = 'Inter distance';
            app.ConvexhullButton = uiradiobutton(app.BalanceButtonGroup);
            app.ConvexhullButton.Text = 'Convex hull';
            app.DensityButton = uiradiobutton(app.BalanceButtonGroup);
            app.DensityButton.Text = 'Density';
            app.EditFieldLabel = uilabel(app.BalanceButtonGroup);
            app.EditFieldLabel.Enable = 'off';
            app.EditFieldLabel.Text = '--';
            app.EditField = uieditfield(app.BalanceButtonGroup, 'numeric');
            app.EditField.Limits = [0 Inf];
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.Enable = 'off';
            app.AcceptederrorLabel = uilabel(app.BalanceButtonGroup);
            app.AcceptederrorLabel.Text = 'Accepted error [%]: ';
            app.AcceptederrorEditField = uieditfield(app.BalanceButtonGroup, 'numeric');
            app.AcceptederrorEditField.Limits = [0 5];
            app.ConvexHull_2 = uilabel(app.UIFigure);
            app.ConvexHull_2.Text = 'Density';
            app.UnitsestimationPanel = uipanel(app.UIFigure);
            app.UnitsestimationPanel.Title = 'Units estimation';
            app.ArenarcmEditFieldLabel = uilabel(app.UnitsestimationPanel);
            app.ArenarcmEditFieldLabel.Text = 'Arena r [cm]';
            app.ArenarcmEditField = uieditfield(app.UnitsestimationPanel, 'numeric');
            app.ArenarcmEditField.ValueChangedFcn = createCallbackFcn(app, @ArenarcmEditFieldValueChanged, true);
            app.pixelLabel = uilabel(app.UnitsestimationPanel);
            app.pixelLabel.Text = '1 pixel = ---';
            app.mmLabel = uilabel(app.UnitsestimationPanel);
            app.mmLabel.Text = '1 mm = ---';
            app.arenashapeSwitch = uiswitch(app.UIFigure, 'slider');
            app.arenashapeSwitch.Items = {'Circle', 'Square'};
            app.arenashapeSwitch.Value = 'Circle';
            app.GeNEsIScreateLabel = uilabel(app.UIFigure);
            app.GeNEsIScreateLabel.Text = 'GeNEsIS - create';
            app.iButton = uibutton(app.UIFigure, 'push');
            app.iButton.ButtonPushedFcn = createCallbackFcn(app, @iButtonPushed, true);
            app.iButton.Text = 'i';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeNEsIS_create

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end