classdef GeNEsIS_save < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        UIAxes                          matlab.ui.control.UIAxes
        InterDistance                   matlab.ui.control.Label
        ConvexHull                      matlab.ui.control.Label
        TotalAreaLabel                  matlab.ui.control.Label
        TotalPerimeterLabel             matlab.ui.control.Label
        r_minLabel                      matlab.ui.control.Label
        r_maxLabel                      matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        LoadButton                      matlab.ui.control.Button
        Generation                      matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        Slider_2                        matlab.ui.control.Slider
        InterDistance_2                 matlab.ui.control.Label
        ConvexHull_2                    matlab.ui.control.Label
        TotalAreaLabel_2                matlab.ui.control.Label
        TotalPerimeterLabel_2           matlab.ui.control.Label
        r_minLabel_2                    matlab.ui.control.Label
        r_maxLabel_2                    matlab.ui.control.Label
        Generation_2                    matlab.ui.control.Label
        LoadButton_2                    matlab.ui.control.Button
        Set1Label                       matlab.ui.control.Label
        Set2Label                       matlab.ui.control.Label
        KeepCheckBox                    matlab.ui.control.CheckBox
        KeepCheckBox_2                  matlab.ui.control.CheckBox
        Previous                        matlab.ui.control.Button
        Next                            matlab.ui.control.Button
        Previous_2                      matlab.ui.control.Button
        Next_2                          matlab.ui.control.Button
        ColorPanel                      matlab.ui.container.Panel
        RGBEditField_2                  matlab.ui.control.EditField
        RGBEditField_3                  matlab.ui.control.EditField
        RGBEditField_4                  matlab.ui.control.EditField
        ElementscolorDropDownLabel      matlab.ui.control.Label
        ElementscolorDropDown           matlab.ui.control.DropDown
        BackgroundfillingDropDownLabel  matlab.ui.control.Label
        BackgroundfillingDropDown       matlab.ui.control.DropDown
        ArenainnercolorDropDownLabel    matlab.ui.control.Label
        ArenainnercolorDropDown         matlab.ui.control.DropDown
        ArenabordercolorDropDownLabel   matlab.ui.control.Label
        ArenabordercolorDropDown        matlab.ui.control.DropDown
        RGB255EditFieldLabel            matlab.ui.control.Label
        RGB255EditField                 matlab.ui.control.EditField
        DimentionsPanel                 matlab.ui.container.Panel
        ArenadimentionpixelEditFieldLabel  matlab.ui.control.Label
        ArenadimentionpixelEditField    matlab.ui.control.NumericEditField
        ArenashapeDropDownLabel         matlab.ui.control.Label
        ArenashapeDropDown              matlab.ui.control.DropDown
        Pixel_XscreenEditFieldLabel     matlab.ui.control.Label
        Pixel_XscreenEditField          matlab.ui.control.NumericEditField
        Pixel_YscreenEditFieldLabel     matlab.ui.control.Label
        Pixel_YscreenEditField          matlab.ui.control.NumericEditField
        WidthxcmEditFieldLabel          matlab.ui.control.Label
        WidthxcmEditField               matlab.ui.control.NumericEditField
        HeightycmEditFieldLabel         matlab.ui.control.Label
        HeightycmEditField              matlab.ui.control.NumericEditField
        ArenadimentioncmEditFieldLabel  matlab.ui.control.Label
        ArenadimentioncmEditField       matlab.ui.control.NumericEditField
        SavePanel                       matlab.ui.container.Panel
        SaveButton                      matlab.ui.control.Button
        SaveemptyarenaButton            matlab.ui.control.Button
        SaveasEditFieldLabel            matlab.ui.control.Label
        SaveasEditField                 matlab.ui.control.EditField
        GeNEsISsaveLabel                matlab.ui.control.Label
        iButton                         matlab.ui.control.Button
    end

    
    properties (Access = private)
        arena;
        ball;
        nBalls;
        nGenerations;
        costraints;
        grp
        ID
        CH
        TA
        TP
        Rm
        RM
    end
    
    methods (Access = private)
        
        function plotArena(app, generation, set)
            rng('default');
            s = rng; 
                
            if set ==1 %plot set1 background
                cla(app.UIAxes)
                hold(app.UIAxes, 'on');
                app.UIAxes.Color=app.arena.color{1};
                bkg=app.BackgroundfillingDropDown.Value;
                
                switch bkg
                    case 'Red dots'
                        pixel=50;
                        x=zeros(pixel,1);
                        y=zeros(pixel,1);
                        rng(s); %fix seed
                        for j=1:pixel
                            for i=1:pixel
                                x(j,i)= (4*app.arena.r)*rand(1)-2*app.arena.r;
                                y(j,i)= (4*app.arena.r)*rand(1)-2*app.arena.r;
                            end
                        end
                        x=x(:);
                        y=y(:);
                        scatter(app.UIAxes,x,y,10,'o','r','filled')
                end
                app.UIAxes.XTick=[];
                app.UIAxes.YTick=[];
                switch app.arena.shape
                    case 's'
                        rectangle(app.UIAxes,'Position', [-app.arena.r, -app.arena.r, app.arena.r*2, app.arena.r*2], 'FaceColor',app.arena.color{2}, 'EdgeColor',app.arena.color{3});
                    case 'c'
                        th = 0:pi/50:2*pi;
                        x_circle = app.arena.r* cos(th);
                        y_circle = app.arena.r * sin(th);
                        plot(app.UIAxes, x_circle, y_circle, 'Color', app.arena.color{2});
                        fill(app.UIAxes, x_circle, y_circle, app.arena.color{2}, 'EdgeColor',app.arena.color{3})

                end
                if app.nBalls{1}>2
                    [~,av] = convhull(app.ball{1}.X{generation});
                    app.ConvexHull.Text=['Convex hull: ' num2str(round(av,2))];
                else
                    app.ConvexHull.Text='Convex hull: -- ';
                end
                D = pdist(app.ball{1}.X{generation});
                app.InterDistance.Text=['Inter distance: ' num2str(round(mean(D),2))];
                
                totalA=0;
                totalP=0;
                for i=1:app.nBalls{1}
                    totalA=totalA+app.ball{1}.A{generation}(i);
                    totalP=totalP+app.ball{1}.P{generation}(i);
                end
                app.TotalAreaLabel.Text=['Total area: ' num2str(round(totalA,2))];
                app.TotalPerimeterLabel.Text=['Total perimeter: ' num2str(round(totalP,2))];
                app.r_minLabel.Text=['r_min: ' num2str(round(min(app.ball{1}.r{generation}),2))];
                app.r_maxLabel.Text=['r_max: ' num2str(round(max(app.ball{1}.r{generation}),2))];
                app.KeepCheckBox.Value=app.ball{1}.keep(generation);
            else %plot set2 background
                cla(app.UIAxes_2)
                hold(app.UIAxes_2, 'on')
                app.UIAxes_2.Color=app.arena.color{1};
                bkg=app.BackgroundfillingDropDown.Value;
                switch bkg
                   case 'Red dots'
                        pixel=50;
                        x=zeros(pixel,1);
                        y=zeros(pixel,1);
                        rng(s); %fix seed
                        for j=1:pixel
                            for i=1:pixel
                                x(j,i)= (4*app.arena.r)*rand(1)-2*app.arena.r;
                                y(j,i)= (4*app.arena.r)*rand(1)-2*app.arena.r;
                            end
                        end
                        x=x(:);
                        y=y(:);
                        scatter(app.UIAxes_2,x,y,10,'o','r','filled')
                end
                app.UIAxes_2.XTick=[];
                app.UIAxes_2.YTick=[];
                app.UIAxes_2.XColor= 'none';
                app.UIAxes_2.YColor= 'none';
                switch app.arena.shape
                    case 's'
                        rectangle(app.UIAxes_2,'Position', [-app.arena.r, -app.arena.r, app.arena.r*2, app.arena.r*2], 'FaceColor',app.arena.color{2}, 'EdgeColor',app.arena.color{3});

                    case 'c'
                        th = 0:pi/50:2*pi;
                        x_circle = app.arena.r* cos(th);
                        y_circle = app.arena.r * sin(th);
                        plot(app.UIAxes_2, x_circle, y_circle, 'Color', app.arena.color{2});
                        fill(app.UIAxes_2, x_circle, y_circle, app.arena.color{2}, 'EdgeColor',app.arena.color{3})

                end
                if app.nBalls{2}>2
                    [~,av] = convhull(app.ball{2}.X{generation});
                    app.ConvexHull_2.Text=['Convex hull: ' num2str(round(av,2))];
                else
                    app.ConvexHull_2.Text='Convex hull: -- ';
                end
                D = pdist(app.ball{2}.X{generation});
                
                app.InterDistance_2.Text=['Inter distance: ' num2str(round(mean(D),2))];
                
                totalA=0;
                totalP=0;
                for i=1:app.nBalls{2}
                    totalA=totalA+app.ball{2}.A{generation}(i);
                    totalP=totalP+app.ball{2}.P{generation}(i);
                end
                app.TotalAreaLabel_2.Text=['Total area: ' num2str(round(totalA,2))];
                app.TotalPerimeterLabel_2.Text=['Total perimeter: ' num2str(round(totalP,2))];
                app.r_minLabel_2.Text=['r_min: ' num2str(round(min(app.ball{2}.r{generation}),2))];
                app.r_maxLabel_2.Text=['r_max: ' num2str(round(max(app.ball{2}.r{generation}),2))];
                app.KeepCheckBox_2.Value=app.ball{2}.keep(generation);
            end
        end
            
        function plotCircle(app, generation, color, set)
            for i=1:app.nBalls{set}
                if app.ball{set}.shape=='c'
                    th = 0:pi/50:2*pi;
                    x_circle = app.ball{set}.r{generation}(i) * cos(th) + app.ball{set}.X{generation}(i,1);
                    y_circle = app.ball{set}.r{generation}(i) * sin(th) + app.ball{set}.X{generation}(i,2);
                    if set==1  
                        plot(app.UIAxes, x_circle, y_circle, 'Color', color,'MarkerEdgeColor',color);
                        fill(app.UIAxes, x_circle, y_circle, color, 'EdgeColor',color)
                    else
                        plot(app.UIAxes_2, x_circle, y_circle, 'Color', color, 'MarkerEdgeColor',color);
                        fill(app.UIAxes_2, x_circle, y_circle, color, 'EdgeColor',color)
                    end
                elseif app.ball{set}.shape=='s'
                    if set==1
                        rectangle(app.UIAxes, 'Position', [app.ball{set}.X{generation}(i,1)-app.ball{set}.r{generation}(i)/2  app.ball{set}.X{generation}(i,2)-app.ball{set}.r{generation}(i)/2 app.ball{set}.r{generation}(i) app.ball{set}.r{generation}(i)], 'FaceColor', color, 'EdgeColor', color);
                    else
                        rectangle(app.UIAxes_2, 'Position', [app.ball{set}.X{generation}(i,1)-app.ball{set}.r{generation}(i)/2  app.ball{set}.X{generation}(i,2)-app.ball{set}.r{generation}(i)/2 app.ball{set}.r{generation}(i) app.ball{set}.r{generation}(i)], 'FaceColor', color, 'EdgeColor', color);
                    end
                elseif app.ball{set}.shape=='d'
                    if set==1
                        h = patch(app.UIAxes, 'XData',[app.ball{set}.X{generation}(i,1)-app.ball{set}.r{generation}(i)/2 app.ball{set}.X{generation}(i,1)+app.ball{set}.r{generation}(i)/2 app.ball{set}.X{generation}(i,1)+app.ball{set}.r{generation}(i)/2 app.ball{set}.X{generation}(i,1)-app.ball{set}.r{generation}(i)/2],'YData',[app.ball{set}.X{generation}(i,2)-app.ball{set}.r{generation}(i)/2 app.ball{set}.X{generation}(i,2)-app.ball{set}.r{generation}(i)/2 app.ball{set}.X{generation}(i,2)+app.ball{set}.r{generation}(i)/2 app.ball{set}.X{generation}(i,2)+app.ball{set}.r{generation}(i)/2],'FaceColor',color, 'EdgeColor', color);
                    else
                        h = patch(app.UIAxes_2, 'XData',[app.ball{set}.X{generation}(i,1)-app.ball{set}.r{generation}(i)/2 app.ball{set}.X{generation}(i,1)+app.ball{set}.r{generation}(i)/2 app.ball{set}.X{generation}(i,1)+app.ball{set}.r{generation}(i)/2 app.ball{set}.X{generation}(i,1)-app.ball{set}.r{generation}(i)/2],'YData',[app.ball{set}.X{generation}(i,2)-app.ball{set}.r{generation}(i)/2 app.ball{set}.X{generation}(i,2)-app.ball{set}.r{generation}(i)/2 app.ball{set}.X{generation}(i,2)+app.ball{set}.r{generation}(i)/2 app.ball{set}.X{generation}(i,2)+app.ball{set}.r{generation}(i)/2],'FaceColor',color, 'EdgeColor', color);
                    end
                    rotate(h, [0 0 1], 45, [app.ball{set}.X{generation}(i,1), app.ball{set}.X{generation}(i,2),0]);
                elseif app.ball{set}.shape=='t'
                    if set==1
                        patch(app.UIAxes, 'XData',[app.ball{set}.X{generation}(i,1)-app.ball{set}.r{generation}(i)/2 app.ball{set}.X{generation}(i,1)+app.ball{set}.r{generation}(i)/2 app.ball{set}.X{generation}(i,1)],'YData',[app.ball{set}.X{generation}(i,2)-sqrt(3)/6*app.ball{set}.r{generation}(i) app.ball{set}.X{generation}(i,2)-sqrt(3)/6*app.ball{set}.r{generation}(i) app.ball{set}.X{generation}(i,2)+sqrt(3)/3*app.ball{set}.r{generation}(i)],'FaceColor',color, 'EdgeColor', color);
                    else
                        patch(app.UIAxes_2, 'XData',[app.ball{set}.X{generation}(i,1)-app.ball{set}.r{generation}(i)/2 app.ball{set}.X{generation}(i,1)+app.ball{set}.r{generation}(i)/2 app.ball{set}.X{generation}(i,1)],'YData',[app.ball{set}.X{generation}(i,2)-sqrt(3)/6*app.ball{set}.r{generation}(i) app.ball{set}.X{generation}(i,2)-sqrt(3)/6*app.ball{set}.r{generation}(i) app.ball{set}.X{generation}(i,2)+sqrt(3)/3*app.ball{set}.r{generation}(i)],'FaceColor',color, 'EdgeColor', color);
                    end
                end
                
            end
            
            if set==1
                app.Generation.Text=[num2str(generation) '/' num2str(app.nGenerations{1})];
            else
                app.Generation_2.Text=[num2str(generation) '/' num2str(app.nGenerations{2})];
            end
        end
        
        function emptyGeneration(app, set, n)
            app.nGenerations{set}=app.nGenerations{set}-1;
            app.ball{set}.X{n}={};
            app.ball{set}.r{n}={};
            app.ball{set}.A{n}={};
            app.ball{set}.P{n}={};
        end
        
        function checkParameters(app,set, generation)
            if app.nBalls{set}>1
                D = pdist(app.ball{set}.X{generation});
                app.ID(generation) = (round(mean(D),2));
            else
                app.ID(generation)=0;
            end
            if app.nBalls{set}>2
                [~,av] = convhull(app.ball{set}.X{generation});
                app.CH(generation)=av;
            else
                app.CH(generation)=0;
            end
            
            totalA=0;
            totalP=0;
            for i=1:app.nBalls{set}
                totalA=totalA+app.ball{set}.A{generation}(i);
                totalP=totalP+app.ball{set}.P{generation}(i);
            end
            app.TA(generation) = round(totalA,2);
            app.TP(generation)=round(totalP,2);
            app.Rm(generation)=round(min(app.ball{set}.r{generation}),2);
            app.RM(generation)=round(max(app.ball{set}.r{generation}),2);
 
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.UIFigure.Name='GeNEsIS - Save balanced images';
            app.arena.shape='c';
            app.arena.r=10;
            app.arena.color{1}=[1, 1, 1];
            app.arena.color{2}=[1, 1, 1];
            app.arena.color{3}=[0, 0, 0];
            sizes=get(0,'MonitorPositions');
            app.Pixel_XscreenEditField.Value=sizes(1,3);
            app.Pixel_YscreenEditField.Value=sizes(1,4);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.UIFigure.Visible='off';
            waitfor(msgbox('Remember to load a GeNEsIS file!', 'Info load file'));
            load(uigetfile, 'sample');
            app.UIFigure.Visible='on';
            if isempty(sample)
                disp('No file GeNEsIS found!')
            else
                disp('GeNEsIS file loaded!')
                app.ball{1}=sample.ball;
                app.nBalls{1}=sample.nBalls;
                app.nGenerations{1}=sample.nGenerations;
                app.costraints{1}=sample.costraints;
                app.ball{1}.color='r';
                if app.nGenerations{1} == 1
                    app.Slider.Enable = 'off';
                else
                    app.Slider.Limits=[1 app.nGenerations{1}];
                    app.Slider.Value=1;
                end
                plotArena(app,1,1)
                plotCircle(app,1, app.ball{1}.color,1);
                app.LoadButton.BackgroundColor=[0 1 0];
                app.LoadButton_2.Enable = 'on';
                figure(app.UIFigure)
            end
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            app.UIFigure.Visible='off';
            load(uigetfile, 'sample');
            app.UIFigure.Visible='on';
            if isempty(sample)
                disp('No file GeNEsIS found!')
            else
                disp('GeNEsIS file loaded!')
                app.ball{2}=sample.ball;
                app.nBalls{2}=sample.nBalls;
                app.nGenerations{2}=sample.nGenerations;
                app.costraints{2}=sample.costraints;
                app.ball{2}.color='r';
                if app.nGenerations{2} == 1
                    app.Slider_2.Enable = 'off';
                else
                    app.Slider_2.Limits=[1 app.nGenerations{1}];
                    app.Slider_2.Value=1;
                end
                plotArena(app,1,2)
                plotCircle(app,1, app.ball{2}.color,2);
                app.LoadButton_2.BackgroundColor=[0 1 0];
                app.BackgroundfillingDropDown.Enable = 'on';
                app.ArenainnercolorDropDown.Enable = 'on';
                app.ElementscolorDropDown.Enable = 'on';
                app.ArenabordercolorDropDown.Enable = 'on';
                app.ArenashapeDropDown.Enable = 'on';
                app.ArenadimentionpixelEditField.Enable = 'on';
                app.ArenadimentioncmEditField.Enable = 'on';
                app.Pixel_XscreenEditField.Enable = 'on';
                app.Pixel_YscreenEditField.Enable = 'on';
                app.WidthxcmEditField.Enable = 'on';
                app.HeightycmEditField.Enable = 'on';
                figure(app.UIFigure)
            end
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            changingValue = round(event.Value);
            plotArena(app, changingValue, 1)
            plotCircle(app, changingValue, app.ball{1}.color, 1)
        end

        % Value changing function: Slider_2
        function Slider_2ValueChanging(app, event)
            changingValue = round(event.Value);
            plotArena(app, changingValue, 2)
            plotCircle(app, changingValue, app.ball{2}.color, 2)
        end

        % Value changed function: ElementscolorDropDown
        function ElementscolorDropDownValueChanged(app, event)
            value = app.ElementscolorDropDown.Value;
            for i=1:2
                switch value
                    case 'Red'
                        app.ball{i}.color=[1,0,0];
                        app.RGBEditField_4.Enable='off';
                    case 'Blue'
                        app.ball{i}.color=[0,0,1];
                        app.RGBEditField_4.Enable='off';
                    case 'Green'
                        app.ball{i}.color=[0,1,0];
                        app.RGBEditField_4.Enable='off';
                    case 'Black'
                        app.ball{i}.color=[0,0,0];
                        app.RGBEditField_4.Enable='off';
                    case 'White'
                        app.ball{i}.color=[1,1,1];
                        app.RGBEditField_4.Enable='off';
                    case 'RGB'
                        app.ball{i}.color=str2num(app.RGBEditField_4.Value)/255;
                        app.RGBEditField_4.Enable='on';
                end
                plotArena(app, 1, i)
                plotCircle(app,1, app.ball{i}.color, i);
            end
            app.Slider.Value=1;
            app.Slider_2.Value=1;   
        end

        % Value changed function: ArenainnercolorDropDown
        function ArenainnercolorDropDownValueChanged(app, event)
            value = app.ArenainnercolorDropDown.Value;
            switch value
                case 'Red'
                    app.arena.color{2}=[1,0,0];
                    app.RGBEditField_2.Enable='off';
                case 'Blue'
                    app.arena.color{2}=[0,0,1];
                    app.RGBEditField_2.Enable='off';
                case 'Green'
                    app.arena.color{2}=[0,1,0];
                    app.RGBEditField_2.Enable='off';
                case 'Black'
                    app.arena.color{2}=[0,0,0];
                    app.RGBEditField_2.Enable='off';
                case 'White'
                    app.arena.color{2}=[1,1,1];
                    app.RGBEditField_2.Enable='off';
                case 'RGB'
                    app.arena.color{2}=str2num(app.RGBEditField_2.Value)/255;
                    app.RGBEditField_2.Enable='on';
            end
            app.Slider.Value=1;
            app.Slider_2.Value=1;
            for i=1:2
                plotArena(app, 1, i)
                plotCircle(app,1, app.ball{i}.color, i);
            end
            figure(app.UIFigure)
        end

        % Value changed function: ArenabordercolorDropDown
        function ArenabordercolorDropDownValueChanged(app, event)
            value = app.ArenabordercolorDropDown.Value;
            switch value
                case 'Red'
                    app.arena.color{3}=[1,0,0];
                    app.RGBEditField_3.Enable='off';
                case 'Blue'
                    app.arena.color{3}=[0,0,1];
                    app.RGBEditField_3.Enable='off';
                case 'Green'
                    app.arena.color{3}=[0,1,0];
                    app.RGBEditField_3.Enable='off';
                case 'Black'
                    app.arena.color{3}=[0,0,0];
                    app.RGBEditField_3.Enable='off';
                case 'White'
                    app.arena.color{3}=[1,1,1];
                    app.RGBEditField_3.Enable='off';
                case 'RGB'
                    app.arena.color{3}=str2num(app.RGBEditField_3.Value)/255;
                    app.RGBEditField_3.Enable='on';
            end
            app.Slider.Value=1;
            app.Slider_2.Value=1;
            for i=1:2
                plotArena(app, 1, i)
                plotCircle(app,1, app.ball{i}.color, i);
            end
            figure(app.UIFigure)
        end

        % Value changed function: BackgroundfillingDropDown
        function BackgroundfillingDropDownValueChanged(app, event)
            value = app.BackgroundfillingDropDown.Value;
            switch value
                case 'Red'
                    app.arena.color{1}=[1,0,0];
                    app.RGB255EditField.Enable='off';
                case 'Blue'
                    app.arena.color{1}=[0,0,1];
                    app.RGB255EditField.Enable='off';
                case 'Green'
                    app.arena.color{1}=[0,1,0];
                    app.RGB255EditField.Enable='off';
                case 'Black'
                    app.arena.color{1}=[0,0,0];
                    app.RGB255EditField.Enable='off';
                case 'White'
                    app.arena.color{1}=[1,1,1];
                    app.RGB255EditField.Enable='off';
                case 'RGB'
                    app.arena.color{1}=str2num(app.RGB255EditField.Value)/255;
                    app.RGB255EditField.Enable='on';
                case 'Red dots'
                    app.RGB255EditField.Enable='off';
            end
            app.Slider.Value=1;
            app.Slider_2.Value=1;
            for i=1:2
                plotArena(app, 1, i)
                plotCircle(app,1, app.ball{i}.color, i);
            end
            figure(app.UIFigure)
        end

        % Value changed function: ArenashapeDropDown
        function ArenashapeDropDownValueChanged(app, event)
            value = app.ArenashapeDropDown.Value;
            switch value
                case 'Circle'
                    app.arena.shape='c';
                case 'Square'
                    app.arena.shape='s';
            end
            set(app.UIAxes,'color',app.arena.color{1})
            app.Slider.Value=1;
            set(app.UIAxes_2,'color',app.arena.color{1})
            app.Slider_2.Value=1;
            for i=1:2
                plotArena(app, 1, i)
                plotCircle(app,1, app.ball{i}.color, i);
            end
        end

        % Value changed function: ArenadimentionpixelEditField
        function ArenadimentionpixelEditFieldValueChanged(app, event)
            app.arena.r = app.ArenadimentionpixelEditField.Value;
            set(app.UIAxes,'color',app.arena.color{1})
            app.Slider.Value=1;
            set(app.UIAxes_2,'color',app.arena.color{1})
            app.Slider_2.Value=1;
            for i=1:2
                plotArena(app, 1, i)
                plotCircle(app,1, app.ball{i}.color, i);
            end
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            rng('default');
            s = rng;    
        
            answer = questdlg('What do you want to save?', 'Save options','Set 1','Set 2','Double view','Set 1');
            switch answer
                case 'Set 1'
                    app.grp=1;
                case 'Set 2'
                    app.grp=2;
                case 'Double view'
                    answer2 = questdlg('Which combination?', 'Save options','Set 1+2','Set 1+1','Set 2+2','Set 1+2');
                    switch answer2
                        case 'Set 1+2'
                            app.grp=3;
                        case 'Set 1+1'
                            app.grp=4;
                        case 'Set 2+2'
                            app.grp=5;
                    end
                    answer3 = questdlg('Which orientation?', 'Save options','Horizontal','Vertical','Horizontal');
                    switch answer3
                        case 'Horizontal'
                            orientation=1;
                        case 'Vertical'
                            orientation=2;
                    end
            end
            
            for sett=1:2
                ballPrev=app.ball{sett};
                nGenerationsPrev=app.nGenerations{sett};
                for i=1:app.nGenerations{sett}
                   if  app.ball{sett}.keep(i)==0
                       emptyGeneration(app,sett, i);
                   end
                end
                app.ball{sett}.X = app.ball{sett}.X(~cellfun(@isempty, app.ball{sett}.X));
                app.ball{sett}.r = app.ball{sett}.r(~cellfun(@isempty, app.ball{sett}.r));
                app.ball{sett}.A = app.ball{sett}.A(~cellfun(@isempty, app.ball{sett}.A));
                app.ball{sett}.P = app.ball{sett}.P(~cellfun(@isempty, app.ball{sett}.P));
                fig.color.b=app.arena.color{1};
                fig.color.a=app.arena.color{2};
                fig.color.l=app.arena.color{3};
                fig.arena.r=app.arena.r;
                fig.arena.s=app.arena.shape;
                hg_cm=app.HeightycmEditField.Value;
                wd_cm=app.WidthxcmEditField.Value;
                hg_pixel=app.Pixel_YscreenEditField.Value;
                wd_pixel=app.Pixel_XscreenEditField.Value;
                radius_cm=app.ArenadimentioncmEditField.Value;
                
                bkg_y=round(fig.arena.r*hg_cm/radius_cm);
                bkg_x=round(fig.arena.r*wd_cm/radius_cm);
                bkg=app.BackgroundfillingDropDown.Value;
            end

            if app.grp==1||app.grp==2
                fig.color.s=app.ball{app.grp}.color;
                fig.ball.shape=app.ball{app.grp}.shape;
                fig.ball.X=app.ball{app.grp}.X;
                fig.ball.r=app.ball{app.grp}.r;
                fig.num.s=app.nBalls{app.grp};
                fig.num.g=app.nGenerations{app.grp};
                
                answer = questdlg('Do you want to save also images in addition to the Matlab matrix?', 'Save images', 'Ok', 'No', 'No');
                f = waitbar(0,'Wait while saving your data...');
                FigH=[];
                for generation=1:fig.num.g
                    waitbar(generation/fig.num.g,f,'Wait while saving your data...');
                    if ishandle(FigH)
                        close(FigH)
                    end
                    FigH=figure('Units','normalized','Position',[0 0 1 1],'visible', 'off');
                    hold on
                    % draw background
                    set(gca,'color',fig.color.b);
                    set(gca,'XColor', 'none','YColor','none')
                    xlim([-bkg_x/2 bkg_x/2]);
                    ylim([-bkg_y/2 bkg_y/2]);
                    rectangle(gca,'Position', [-bkg_x/2, -bkg_y/2, bkg_x, bkg_y], 'FaceColor',fig.color.b, 'EdgeColor', fig.color.b);
                    switch bkg
                        case 'Red dots'
                        pixel=50;
                        x=zeros(pixel,1);
                        y=zeros(pixel,1);
                        rng(s); %fix seed
                        for j=1:pixel
                            for i=1:pixel
                                x(j,i)= (4*app.arena.r)*rand(1)-2*app.arena.r;
                                y(j,i)= (4*app.arena.r)*rand(1)-2*app.arena.r;
                            end
                        end
                        x=x(:);
                        y=y(:);
                        scatter(x,y,80,'o','r','filled') %dots dimention fixed at 80
                    end
                    % draw arena contour
                    switch fig.arena.s
                        case 's'
                            rectangle('Position', [-fig.arena.r, -fig.arena.r, fig.arena.r*2, fig.arena.r*2], 'FaceColor',fig.color.a, 'EdgeColor',fig.color.l);
                        case 'c'
                            th = 0:pi/50:2*pi;
                            x_circle = fig.arena.r* cos(th);
                            y_circle = fig.arena.r * sin(th);
                            plot(x_circle, y_circle, 'Color', fig.color.a);
                            fill(x_circle, y_circle, fig.color.a,'EdgeColor', fig.color.l)
                    end
                    % draw elements
                    for i=1:fig.num.s
                        if fig.ball.shape=='c'
                            th = 0:pi/50:2*pi;
                            x_circle = fig.ball.r{generation}(i) * cos(th) + fig.ball.X{generation}(i,1);
                            y_circle = fig.ball.r{generation}(i) * sin(th) + fig.ball.X{generation}(i,2);
                            plot(x_circle, y_circle, 'Color', fig.color.s);
                            fill(x_circle, y_circle, fig.color.s, 'EdgeColor', fig.color.s)
                        elseif fig.ball.shape=='s'
                            rectangle('Position', [fig.ball.X{generation}(i,1)-fig.ball.r{generation}(i)/2  fig.ball.X{generation}(i,2)-fig.ball.r{generation}(i)/2 fig.ball.r{generation}(i) fig.ball.r{generation}(i)], 'FaceColor', fig.color.s,'EdgeColor', fig.color.s);
                        elseif fig.ball.shape=='d'
                            h = patch('XData',[fig.ball.X{generation}(i,1)-fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,1)+fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,1)+fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,1)-fig.ball.r{generation}(i)/2],'YData',[fig.ball.X{generation}(i,2)-fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,2)-fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,2)+fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,2)+fig.ball.r{generation}(i)/2],'FaceColor',fig.color.s, 'EdgeColor',fig.color.s);
                            rotate(h, [0 0 1], 45, [fig.ball.X{generation}(i,1), fig.ball.X{generation}(i,2),0]);
                        elseif fig.ball.shape=='t'
                            patch('XData',[fig.ball.X{generation}(i,1)-fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,1)+fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,1)],'YData',[fig.ball.X{generation}(i,2)-sqrt(3)/6*fig.ball.r{generation}(i) fig.ball.X{generation}(i,2)-sqrt(3)/6*fig.ball.r{generation}(i) fig.ball.X{generation}(i,2)+sqrt(3)/3*fig.ball.r{generation}(i)],'FaceColor',fig.color.s, 'EdgeColor',fig.color.s);
                        end
                    end
                    %save
                    name=app.SaveasEditField.Value;
                    h = getframe(gca); %current figure handle
                    [X, ~] = frame2im(h);
                    J = imresize(X,[hg_pixel wd_pixel]);
                    if answer=='Ok'
                        %imwrite(J,fullfile(folder,[name '_' num2str(generation)]),'png');
                        imwrite(J,[name '_' num2str(generation) '.png']);
                    end
                    imgMatrix{generation}=J;
                end
                close(f)
                %save(fullfile(folder,name), 'imgMatrix')
                save(name, 'imgMatrix')
                app.nGenerations{app.grp}=nGenerationsPrev;
                app.ball{app.grp}=ballPrev;
                
                app.CH=zeros(app.nGenerations{app.grp},1);
                app.ID=zeros(app.nGenerations{app.grp},1);
                app.TA=zeros(app.nGenerations{app.grp},1);
                app.TP=zeros(app.nGenerations{app.grp},1);
                app.Rm=zeros(app.nGenerations{app.grp},1);
                app.RM=zeros(app.nGenerations{app.grp},1);
                for generation=1:app.nGenerations{app.grp}
                    if app.ball{app.grp}.keep(generation)==1
                        checkParameters(app,app.grp,generation);
                    end
                end
                stimNum=1:app.nGenerations{app.grp};
                T=table(stimNum', app.CH, app.ID, app.TA, app.TP, app.Rm, app.RM);
                T.Properties.VariableNames = {'Stimulus_number','CH', 'ID', 'TA', 'TP', 'Rm', 'RM'};
                writetable(T,[app.SaveasEditField.Value '.xlsx'],'Sheet',1)
                %for precise visualization of ms in presentation time save as file txt
                %writetable(T,[app.SaveinfostoEditField.Value '.txt'])
                    
            elseif app.grp==3
                fig.num.g=min(app.nGenerations{1}, app.nGenerations{2});
                answer = questdlg('Do you want to save also images in addition to the Matlab matrix?', 'Save images', 'Ok', 'No', 'No');
                if orientation==1
                    bkg_x=bkg_x/2;
                    wd_pixel=wd_pixel/2;
                else
                    bkg_y=bkg_y/2;
                    hg_pixel=hg_pixel/2;
                end
                f = waitbar(0,'Wait while saving your data ...');
                for generation=1:fig.num.g
                    for k=1:2
                        fig.color.s=app.ball{k}.color;
                        fig.ball.shape=app.ball{k}.shape;
                        fig.ball.X=app.ball{k}.X;
                        fig.ball.r=app.ball{k}.r;
                        fig.num.s=app.nBalls{k};
                        
                        app.UIFigure.Visible='on';
                        FigH{k}=[];
               
                        waitbar(generation/fig.num.g,f, 'Wait while saving your data ...');
                        if ishandle(FigH{k})
                            close(FigH{k})
                        end
                        FigH{k}=figure('Units','normalized','Position',[0 0 1 1],'visible', 'off');
                        hold on
                        % draw background around arena
                        set(gca,'color',fig.color.b);
                        set(gca,'XColor', 'none','YColor','none')
                        xlim([-bkg_x/2 bkg_x/2]);
                        ylim([-bkg_y/2 bkg_y/2]);
                        rectangle(gca,'Position', [-bkg_x/2, -bkg_y/2, bkg_x, bkg_y], 'FaceColor',fig.color.b, 'EdgeColor', fig.color.b);
                        switch bkg
                            case 'Red dots'
                            pixel=50;
                            x=zeros(pixel,1);
                            y=zeros(pixel,1);
                            rng(s); %fix seed
                            for j=1:pixel
                                for i=1:pixel
                                    x(j,i)= (4*app.arena.r)*rand(1)-2*app.arena.r;
                                    y(j,i)= (4*app.arena.r)*rand(1)-2*app.arena.r;
                                end
                            end
                            x=x(:);
                            y=y(:);
                            scatter(x,y,80,'o','r','filled') %dots dimention fixed at 80
                        end
                        % draw arena contour
                        switch fig.arena.s
                            case 's'
                                rectangle('Position', [-fig.arena.r, -fig.arena.r, fig.arena.r*2, fig.arena.r*2], 'FaceColor',fig.color.a, 'EdgeColor',fig.color.l);
                            case 'c'
                                th = 0:pi/50:2*pi;
                                x_circle = fig.arena.r* cos(th);
                                y_circle = fig.arena.r * sin(th);
                                plot(x_circle, y_circle, 'Color', fig.color.a);
                                fill(x_circle, y_circle, fig.color.a,'EdgeColor', fig.color.l)
                        end
                        % draw elements
                        for i=1:fig.num.s
                            if fig.ball.shape=='c'
                                th = 0:pi/50:2*pi;
                                x_circle = fig.ball.r{generation}(i) * cos(th) + fig.ball.X{generation}(i,1);
                                y_circle = fig.ball.r{generation}(i) * sin(th) + fig.ball.X{generation}(i,2);
                                plot(x_circle, y_circle, 'Color', fig.color.s);
                                fill(x_circle, y_circle, fig.color.s, 'EdgeColor', fig.color.s)
                            elseif fig.ball.shape=='s'
                                rectangle('Position', [fig.ball.X{generation}(i,1)-fig.ball.r{generation}(i)/2  fig.ball.X{generation}(i,2)-fig.ball.r{generation}(i)/2 fig.ball.r{generation}(i) fig.ball.r{generation}(i)], 'FaceColor', fig.color.s,'EdgeColor', fig.color.s);
                            elseif fig.ball.shape=='d'
                                h = patch('XData',[fig.ball.X{generation}(i,1)-fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,1)+fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,1)+fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,1)-fig.ball.r{generation}(i)/2],'YData',[fig.ball.X{generation}(i,2)-fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,2)-fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,2)+fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,2)+fig.ball.r{generation}(i)/2],'FaceColor',fig.color.s, 'EdgeColor',fig.color.s);
                                rotate(h, [0 0 1], 45, [fig.ball.X{generation}(i,1), fig.ball.X{generation}(i,2),0]);
                            elseif fig.ball.shape=='t'
                                patch('XData',[fig.ball.X{generation}(i,1)-fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,1)+fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,1)],'YData',[fig.ball.X{generation}(i,2)-sqrt(3)/6*fig.ball.r{generation}(i) fig.ball.X{generation}(i,2)-sqrt(3)/6*fig.ball.r{generation}(i) fig.ball.X{generation}(i,2)+sqrt(3)/3*fig.ball.r{generation}(i)],'FaceColor',fig.color.s, 'EdgeColor',fig.color.s);
                            end
                        end
                        %save
                        name=[app.SaveasEditField.Value num2str(k)];
                        h = getframe(gca); %current figure handle
                        [X, ~] = frame2im(h);
                        J{k} = imresize(X,[hg_pixel wd_pixel]);
                    end
                    %merge two images
                    if orientation==1
                        Jmerge=[J{1} J{2}];
                    else
                        Jmerge=[J{1}; J{2}];
                    end
                    name=app.SaveasEditField.Value;
                    if answer=='Ok'
                        %imwrite(Jmerge,fullfile(folder,[name '_' num2str(generation)]),'png');
                        imwrite(Jmerge,[name '_' num2str(generation) '.png']);
                    end
                    imgMatrix{generation}=Jmerge;
                end
                close(f)
                %save(fullfile(folder,name), 'imgMatrix')
                save(name, 'imgMatrix')
                app.nGenerations{app.grp}=nGenerationsPrev;
                app.ball{app.grp}=ballPrev;
                
            else
                if app.grp==4
                    sett=1;
                else
                    sett=2;
                end
                fig.num.g=app.nGenerations{sett};
                answer = questdlg('Do you want to save also images in addition to the Matlab matrix?', 'Save images', 'Ok', 'No', 'No');
                if orientation==1
                    bkg_x=bkg_x/2;
                    wd_pixel=wd_pixel/2;
                else
                    bkg_y=bkg_y/2;
                    hg_pixel=hg_pixel/2;
                end
                f = waitbar(0,'Wait while saving your data ...');
                for generation=1:fig.num.g
                    fig.color.s=app.ball{sett}.color;
                    fig.ball.shape=app.ball{sett}.shape;
                    fig.ball.X=app.ball{sett}.X;
                    fig.ball.r=app.ball{sett}.r;
                    fig.num.s=app.nBalls{sett};
                    
                    app.UIFigure.Visible='on';
                    FigH=[];
           
                    waitbar(generation/fig.num.g,f, 'Wait while saving your data ...');
                    if ishandle(FigH)
                        close(FigH)
                    end
                    FigH=figure('Units','normalized','Position',[0 0 1 1],'visible', 'off');
                    hold on
                    % draw background around arena
                    set(gca,'color',fig.color.b);
                    set(gca,'XColor', 'none','YColor','none')
                    xlim([-bkg_x/2 bkg_x/2]);
                    ylim([-bkg_y/2 bkg_y/2]);
                    rectangle(gca,'Position', [-bkg_x/2, -bkg_y/2, bkg_x, bkg_y], 'FaceColor',fig.color.b, 'EdgeColor', fig.color.b);
                    switch bkg
                        case 'Red dots'
                            pixel=50;
                            x=zeros(pixel,1);
                            y=zeros(pixel,1);
                            rng(s); %fix seed
                            for j=1:pixel
                                for i=1:pixel
                                    x(j,i)= (4*app.arena.r)*rand(1)-2*app.arena.r;
                                    y(j,i)= (4*app.arena.r)*rand(1)-2*app.arena.r;
                                end
                            end
                            x=x(:);
                            y=y(:);
                            scatter(x,y,80,'o','r','filled') %dots dimension fixed at 80
                    end
                    % draw arena contour
                    switch fig.arena.s
                        case 's'
                            rectangle('Position', [-fig.arena.r, -fig.arena.r, fig.arena.r*2, fig.arena.r*2], 'FaceColor',fig.color.a, 'EdgeColor',fig.color.l);
                        case 'c'
                            th = 0:pi/50:2*pi;
                            x_circle = fig.arena.r* cos(th);
                            y_circle = fig.arena.r * sin(th);
                            plot(x_circle, y_circle, 'Color', fig.color.a);
                            fill(x_circle, y_circle, fig.color.a,'EdgeColor', fig.color.l)
                    end
                    % draw elements
                    for i=1:fig.num.s
                        if fig.ball.shape=='c'
                            th = 0:pi/50:2*pi;
                            x_circle = fig.ball.r{generation}(i) * cos(th) + fig.ball.X{generation}(i,1);
                            y_circle = fig.ball.r{generation}(i) * sin(th) + fig.ball.X{generation}(i,2);
                            plot(x_circle, y_circle, 'Color', fig.color.s);
                            fill(x_circle, y_circle, fig.color.s, 'EdgeColor', fig.color.s)
                        elseif fig.ball.shape=='s'
                            rectangle('Position', [fig.ball.X{generation}(i,1)-fig.ball.r{generation}(i)/2  fig.ball.X{generation}(i,2)-fig.ball.r{generation}(i)/2 fig.ball.r{generation}(i) fig.ball.r{generation}(i)], 'FaceColor', fig.color.s,'EdgeColor', fig.color.s);
                        elseif fig.ball.shape=='d'
                            h = patch('XData',[fig.ball.X{generation}(i,1)-fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,1)+fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,1)+fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,1)-fig.ball.r{generation}(i)/2],'YData',[fig.ball.X{generation}(i,2)-fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,2)-fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,2)+fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,2)+fig.ball.r{generation}(i)/2],'FaceColor',fig.color.s, 'EdgeColor',fig.color.s);
                            rotate(h, [0 0 1], 45, [fig.ball.X{generation}(i,1), fig.ball.X{generation}(i,2),0]);
                        elseif fig.ball.shape=='t'
                            patch('XData',[fig.ball.X{generation}(i,1)-fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,1)+fig.ball.r{generation}(i)/2 fig.ball.X{generation}(i,1)],'YData',[fig.ball.X{generation}(i,2)-sqrt(3)/6*fig.ball.r{generation}(i) fig.ball.X{generation}(i,2)-sqrt(3)/6*fig.ball.r{generation}(i) fig.ball.X{generation}(i,2)+sqrt(3)/3*fig.ball.r{generation}(i)],'FaceColor',fig.color.s, 'EdgeColor',fig.color.s);
                        end
                    end
                    %save
                    name=app.SaveasEditField.Value;
                    h = getframe(gca); %current figure handle
                    [X, ~] = frame2im(h);
                    J = imresize(X,[hg_pixel wd_pixel]);

                    %merge two images
                    if orientation==1
                        Jmerge=[J J];
                    else
                        Jmerge=[J; J];
                    end
                    name=app.SaveasEditField.Value;
                    if answer=='Ok'
                        %imwrite(Jmerge,fullfile(folder,[name '_' num2str(generation)]),'png');
                        imwrite(Jmerge,[name '_' num2str(generation) '.png']);
                    end
                    imgMatrix{generation}=Jmerge;
                end
                close(f)
                %save(fullfile(folder,name), 'imgMatrix')
                save(name, 'imgMatrix')
                app.nGenerations{app.grp}=nGenerationsPrev;
                app.ball{app.grp}=ballPrev;
            end
            disp('Images saved!')

        end

        % Value changed function: KeepCheckBox
        function KeepCheckBoxValueChanged(app, event)
            value = app.KeepCheckBox.Value;
            app.ball{1}.keep(uint32(app.Slider.Value))=value;
        end

        % Value changed function: KeepCheckBox_2
        function KeepCheckBox_2ValueChanged(app, event)
            value = app.KeepCheckBox_2.Value;
            app.ball{2}.keep(uint32(app.Slider.Value))=value;
        end

        % Button pushed function: Previous
        function PreviousButtonPushed(app, event)
            if round(app.Slider.Value)~=1
                app.Slider.Value=round(app.Slider.Value)-1;
            end
            plotArena(app, app.Slider.Value, 1)
            plotCircle(app,app.Slider.Value, app.ball{1}.color, 1);
        end

        % Button pushed function: Next
        function NextButtonPushed(app, event)
            if round(app.Slider.Value)~=app.nGenerations{1}
                app.Slider.Value=round(app.Slider.Value)+1;
            end
            plotArena(app, app.Slider.Value, 1)
            plotCircle(app,app.Slider.Value, app.ball{1}.color, 1);
        end

        % Button pushed function: Previous_2
        function Previous_2ButtonPushed(app, event)
            if round(app.Slider_2.Value)~=1
                app.Slider_2.Value=round(app.Slider_2.Value)-1;
            end
            plotArena(app, app.Slider_2.Value, 2)
            plotCircle(app,app.Slider_2.Value, app.ball{2}.color, 2);
        end

        % Button pushed function: Next_2
        function Next_2ButtonPushed(app, event)
            if round(app.Slider_2.Value)~=app.nGenerations{2}
                app.Slider_2.Value=round(app.Slider_2.Value)+1;
            end
            plotArena(app, app.Slider_2.Value, 2)
            plotCircle(app,app.Slider_2.Value, app.ball{2}.color, 2);
        end

        % Button pushed function: SaveemptyarenaButton
        function SaveemptyarenaButtonPushed(app, event)
            %fix seed to have backgrorund dots fixed
            rng('default');
            s = rng;
            
            fig.color.b=app.arena.color{1};
            fig.color.a=app.arena.color{2};
            fig.color.l=app.arena.color{3};
            fig.arena.r=app.arena.r;
            fig.arena.s=app.arena.shape;
            hg_cm=app.HeightycmEditField.Value;
            wd_cm=app.WidthxcmEditField.Value;
            hg_pixel=app.Pixel_YscreenEditField.Value;
            wd_pixel=app.Pixel_XscreenEditField.Value;
            radius_cm=app.ArenadimentioncmEditField.Value;
            
            bkg_y=round(fig.arena.r*hg_cm/radius_cm);
            bkg_x=round(fig.arena.r*wd_cm/radius_cm);
            bkg=app.BackgroundfillingDropDown.Value;
                
            app.UIFigure.Visible='on';
            answer = questdlg('Do you want to save an image in addition to the Matlab matrix?', 'Save image', 'Ok', 'No', 'No');
            FigH=[];
            if ishandle(FigH)
                close(FigH)
            end
            FigH=figure('Units','normalized','Position',[0 0 1 1],'visible', 'off');
            hold on
            % draw background
            set(gca,'color',fig.color.b);
            set(gca,'XColor', 'none','YColor','none')
            xlim([-bkg_x/2 bkg_x/2]);
            ylim([-bkg_y/2 bkg_y/2]);
            rectangle(gca,'Position', [-bkg_x/2, -bkg_y/2, bkg_x, bkg_y], 'FaceColor',fig.color.b, 'EdgeColor', fig.color.b);
            switch bkg
                case 'Red dots'
                pixel=50;
                x=zeros(pixel,1);
                y=zeros(pixel,1);
                rng(s); %fix seed
                for j=1:pixel
                    for i=1:pixel
                        x(j,i)= (4*app.arena.r)*rand(1)-2*app.arena.r;
                        y(j,i)= (4*app.arena.r)*rand(1)-2*app.arena.r;
                    end
                end
                x=x(:);
                y=y(:);
                scatter(x,y,80,'o','r','filled') %dots dimention fixed at 80
                %save only scattered background
                name=app.SaveasEditField.Value;
                h = getframe(gca); %current figure handle
                [X, ~] = frame2im(h);
                J = imresize(X,[hg_pixel wd_pixel]);
                if answer=='Ok'
                    %imwrite(J,fullfile(folder,[name '_' num2str(generation)]),'png');
                    imwrite(J,[name '_bkgEmpty.png']);
                end
                imgBkg{1}=J;
            end
           
            % draw arena contour
            switch fig.arena.s
                case 's'
                    rectangle('Position', [-fig.arena.r, -fig.arena.r, fig.arena.r*2, fig.arena.r*2], 'FaceColor',fig.color.a, 'EdgeColor',fig.color.l);
                case 'c'
                    th = 0:pi/50:2*pi;
                    x_circle = fig.arena.r* cos(th);
                    y_circle = fig.arena.r * sin(th);
                    plot(x_circle, y_circle, 'Color', fig.color.a);
                    fill(x_circle, y_circle, fig.color.a,'EdgeColor', fig.color.l)
            end
            %save background+arena
            name=app.SaveasEditField.Value;
            h = getframe(gca); %current figure handle
            [X, ~] = frame2im(h);
            J = imresize(X,[hg_pixel wd_pixel]);
            if answer=='Ok'
                %imwrite(J,fullfile(folder,[name '_' num2str(generation)]),'png');
                imwrite(J,[name '_bkgCircle.png']);
            end
            imgBkg{2}=J;
            %save(fullfile(folder,name), 'imgMatrix')
            save([name '_bkg'], 'imgBkg')
        end

        % Value changed function: RGB255EditField
        function RGB255EditFieldValueChanged(app, event)
            value = app.RGB255EditField.Value;
            app.arena.color{1}=str2num(value)/255;
            app.Slider.Value=1;
            app.Slider_2.Value=1;
            for i=1:2
                plotArena(app, 1, i)
                plotCircle(app,1, app.ball{i}.color, i);
            end
            figure(app.UIFigure)
        end

        % Value changed function: RGBEditField_2
        function RGBEditField_2ValueChanged(app, event)
            value = app.RGBEditField_2.Value;
            app.arena.color{2}=str2num(value)/255;
            app.Slider.Value=1;
            app.Slider_2.Value=1;
            for i=1:2
                plotArena(app, 1, i)
                plotCircle(app,1, app.ball{i}.color, i);
            end
            figure(app.UIFigure)
        end

        % Value changed function: RGBEditField_3
        function RGBEditField_3ValueChanged(app, event)
            value = app.RGBEditField_3.Value;
            app.arena.color{3}=str2num(value)/255;
            app.Slider.Value=1;
            app.Slider_2.Value=1;
            for i=1:2
                plotArena(app, 1, i)
                plotCircle(app,1, app.ball{i}.color, i);
            end
            figure(app.UIFigure)
        end

        % Value changed function: RGBEditField_4
        function RGBEditField_4ValueChanged(app, event)
            value = app.RGBEditField_4.Value;
            for i=1:2               
                app.ball{i}.color=str2num(value)/255;
                plotArena(app, 1, i)
                plotCircle(app,1, app.ball{i}.color, i);
            end
            figure(app.UIFigure)
            app.Slider.Value=1;
            app.Slider_2.Value=1;
        end

        % Value changed function: WidthxcmEditField
        function WidthxcmEditFieldValueChanged(app, event)
            value = app.WidthxcmEditField.Value;
            if app.HeightycmEditField.Value~=0 && ~isempty(app.SaveasEditField.Value)
                app.SaveButton.Enable='on';
                app.SaveemptyarenaButton.Enable = 'on';
            end
        end

        % Value changed function: HeightycmEditField
        function HeightycmEditFieldValueChanged(app, event)
            value = app.HeightycmEditField.Value;
            if app.WidthxcmEditField.Value~=0 && ~isempty(app.SaveasEditField.Value)
                app.SaveButton.Enable='on';
                app.SaveemptyarenaButton.Enable = 'on';
            end
        end

        % Value changed function: SaveasEditField
        function SaveasEditFieldValueChanged(app, event)
            value = app.SaveasEditField.Value;
            if app.HeightycmEditField.Value~=0 && app.WidthxcmEditField.Value~=0
                app.SaveButton.Enable='on';
                app.SaveemptyarenaButton.Enable = 'on';
            end
        end

        % Button pushed function: iButton
        function iButtonPushed(app, event)
            winopen('./Tutorial/GeNEsIS_save_tutorial.pdf')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.InterDistance = uilabel(app.UIFigure);
            app.InterDistance.Text = 'Inter distance:';
            app.ConvexHull = uilabel(app.UIFigure);
            app.ConvexHull.Text = 'Convex hull:';
            app.TotalAreaLabel = uilabel(app.UIFigure);
            app.TotalAreaLabel.Text = 'Total area:';
            app.TotalPerimeterLabel = uilabel(app.UIFigure);
            app.TotalPerimeterLabel.Text = 'Total perimeter:';
            app.r_minLabel = uilabel(app.UIFigure);
            app.r_minLabel.Text = 'r_min:';
            app.r_maxLabel = uilabel(app.UIFigure);
            app.r_maxLabel.Text = 'r_max:';
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [1 100000];
            app.Slider.MajorTicks = [];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.MinorTicks = [];
            app.Slider.Value = 1;
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.Generation = uilabel(app.UIFigure);
            app.Generation.Text = 'Generation';
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [1 100000];
            app.Slider_2.MajorTicks = [];
            app.Slider_2.ValueChangingFcn = createCallbackFcn(app, @Slider_2ValueChanging, true);
            app.Slider_2.MinorTicks = [];
            app.Slider_2.Value = 1;
            app.InterDistance_2 = uilabel(app.UIFigure);
            app.InterDistance_2.Text = 'Inter distance:';
            app.ConvexHull_2 = uilabel(app.UIFigure);
            app.ConvexHull_2.Text = 'Convex hull:';
            app.TotalAreaLabel_2 = uilabel(app.UIFigure);
            app.TotalAreaLabel_2.Text = 'Total area:';
            app.TotalPerimeterLabel_2 = uilabel(app.UIFigure);
            app.TotalPerimeterLabel_2.Text = 'Total perimeter:';
            app.r_minLabel_2 = uilabel(app.UIFigure);
            app.r_minLabel_2.Text = 'r_min:';
            app.r_maxLabel_2 = uilabel(app.UIFigure);
            app.r_maxLabel_2.Text = 'r_max:';
            app.Generation_2 = uilabel(app.UIFigure);
            app.Generation_2.Text = 'Generation';
            app.LoadButton_2 = uibutton(app.UIFigure, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Enable = 'off';
            app.LoadButton_2.Text = 'Load';
            app.Set1Label = uilabel(app.UIFigure);
            app.Set1Label.Text = 'Set 1';
            app.Set2Label = uilabel(app.UIFigure);
            app.Set2Label.Text = 'Set 2';
            app.KeepCheckBox = uicheckbox(app.UIFigure);
            app.KeepCheckBox.ValueChangedFcn = createCallbackFcn(app, @KeepCheckBoxValueChanged, true);
            app.KeepCheckBox.Text = 'Keep';
            app.KeepCheckBox.Value = true;
            app.KeepCheckBox_2 = uicheckbox(app.UIFigure);
            app.KeepCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @KeepCheckBox_2ValueChanged, true);
            app.KeepCheckBox_2.Text = 'Keep';
            app.KeepCheckBox_2.Value = true;
            app.Previous = uibutton(app.UIFigure, 'push');
            app.Previous.ButtonPushedFcn = createCallbackFcn(app, @PreviousButtonPushed, true);
            app.Previous.Text = '<<';
            app.Next = uibutton(app.UIFigure, 'push');
            app.Next.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushed, true);
            app.Next.Text = '>>';
            app.Previous_2 = uibutton(app.UIFigure, 'push');
            app.Previous_2.ButtonPushedFcn = createCallbackFcn(app, @Previous_2ButtonPushed, true);
            app.Previous_2.Text = '<<';
            app.Next_2 = uibutton(app.UIFigure, 'push');
            app.Next_2.ButtonPushedFcn = createCallbackFcn(app, @Next_2ButtonPushed, true);
            app.Next_2.Text = '>>';
            app.ColorPanel = uipanel(app.UIFigure);
            app.ColorPanel.Title = 'Color';
            app.RGBEditField_2 = uieditfield(app.ColorPanel, 'text');
            app.RGBEditField_2.ValueChangedFcn = createCallbackFcn(app, @RGBEditField_2ValueChanged, true);
            app.RGBEditField_2.Enable = 'off';
            app.RGBEditField_2.Value = '0, 0, 0';
            app.RGBEditField_3 = uieditfield(app.ColorPanel, 'text');
            app.RGBEditField_3.ValueChangedFcn = createCallbackFcn(app, @RGBEditField_3ValueChanged, true);
            app.RGBEditField_3.Enable = 'off';
            app.RGBEditField_3.Value = '0, 0, 0';
            app.RGBEditField_4 = uieditfield(app.ColorPanel, 'text');
            app.RGBEditField_4.ValueChangedFcn = createCallbackFcn(app, @RGBEditField_4ValueChanged, true);
            app.RGBEditField_4.Enable = 'off';
            app.RGBEditField_4.Value = '0, 0, 0';
            app.ElementscolorDropDownLabel = uilabel(app.ColorPanel);
            app.ElementscolorDropDownLabel.Text = 'Elements color';
            app.ElementscolorDropDown = uidropdown(app.ColorPanel);
            app.ElementscolorDropDown.Items = {'Red', 'Blue', 'Green', 'White', 'Black', 'RGB'};
            app.ElementscolorDropDown.ValueChangedFcn = createCallbackFcn(app, @ElementscolorDropDownValueChanged, true);
            app.ElementscolorDropDown.Enable = 'off';
            app.ElementscolorDropDown.Value = 'Red';
            app.BackgroundfillingDropDownLabel = uilabel(app.ColorPanel);
            app.BackgroundfillingDropDownLabel.Text = 'Background filling';
            app.BackgroundfillingDropDown = uidropdown(app.ColorPanel);
            app.BackgroundfillingDropDown.Items = {'Red', 'Blue', 'Green', 'White', 'Black', 'RGB', 'Red dots'};
            app.BackgroundfillingDropDown.ValueChangedFcn = createCallbackFcn(app, @BackgroundfillingDropDownValueChanged, true);
            app.BackgroundfillingDropDown.Enable = 'off';
            app.BackgroundfillingDropDown.Value = 'White';
            app.ArenainnercolorDropDownLabel = uilabel(app.ColorPanel);
            app.ArenainnercolorDropDownLabel.Text = 'Arena inner color';
            app.ArenainnercolorDropDown = uidropdown(app.ColorPanel);
            app.ArenainnercolorDropDown.Items = {'Red', 'Blue', 'Green', 'White', 'Black', 'RGB'};
            app.ArenainnercolorDropDown.ValueChangedFcn = createCallbackFcn(app, @ArenainnercolorDropDownValueChanged, true);
            app.ArenainnercolorDropDown.Enable = 'off';
            app.ArenainnercolorDropDown.Value = 'White';
            app.ArenabordercolorDropDownLabel = uilabel(app.ColorPanel);
            app.ArenabordercolorDropDownLabel.Text = 'Arena border color';
            app.ArenabordercolorDropDown = uidropdown(app.ColorPanel);
            app.ArenabordercolorDropDown.Items = {'Red', 'Blue', 'Green', 'White', 'Black', 'RGB'};
            app.ArenabordercolorDropDown.ValueChangedFcn = createCallbackFcn(app, @ArenabordercolorDropDownValueChanged, true);
            app.ArenabordercolorDropDown.Enable = 'off';
            app.ArenabordercolorDropDown.Value = 'Black';
            app.RGB255EditFieldLabel = uilabel(app.ColorPanel);
            app.RGB255EditFieldLabel.Text = 'RGB [255]';
            app.RGB255EditField = uieditfield(app.ColorPanel, 'text');
            app.RGB255EditField.ValueChangedFcn = createCallbackFcn(app, @RGB255EditFieldValueChanged, true);
            app.RGB255EditField.Enable = 'off';
            app.RGB255EditField.Value = '0, 0, 0';
            app.DimentionsPanel = uipanel(app.UIFigure);
            app.DimentionsPanel.Title = 'Dimentions';
            app.ArenadimentionpixelEditFieldLabel = uilabel(app.DimentionsPanel);
            app.ArenadimentionpixelEditFieldLabel.Text = 'Arena dimention [pixel]';
            app.ArenadimentionpixelEditField = uieditfield(app.DimentionsPanel, 'numeric');
            app.ArenadimentionpixelEditField.Limits = [1 1000];
            app.ArenadimentionpixelEditField.ValueChangedFcn = createCallbackFcn(app, @ArenadimentionpixelEditFieldValueChanged, true);
            app.ArenadimentionpixelEditField.Enable = 'off';
            app.ArenadimentionpixelEditField.Value = 10;
            app.ArenashapeDropDownLabel = uilabel(app.DimentionsPanel);
            app.ArenashapeDropDownLabel.Text = 'Arena shape';
            app.ArenashapeDropDown = uidropdown(app.DimentionsPanel);
            app.ArenashapeDropDown.Items = {'Circle', 'Square'};
            app.ArenashapeDropDown.ValueChangedFcn = createCallbackFcn(app, @ArenashapeDropDownValueChanged, true);
            app.ArenashapeDropDown.Enable = 'off';
            app.ArenashapeDropDown.Value = 'Circle';
            app.Pixel_XscreenEditFieldLabel = uilabel(app.DimentionsPanel);
            app.Pixel_XscreenEditFieldLabel.Text = 'Pixel_X screen';
            app.Pixel_XscreenEditField = uieditfield(app.DimentionsPanel, 'numeric');
            app.Pixel_XscreenEditField.Enable = 'off';
            app.Pixel_YscreenEditFieldLabel = uilabel(app.DimentionsPanel);
            app.Pixel_YscreenEditFieldLabel.Text = 'Pixel_Y screen';
            app.Pixel_YscreenEditField = uieditfield(app.DimentionsPanel, 'numeric');
            app.Pixel_YscreenEditField.Enable = 'off';
            app.WidthxcmEditFieldLabel = uilabel(app.DimentionsPanel);
            app.WidthxcmEditFieldLabel.Text = 'Width x [cm]';
            app.WidthxcmEditField = uieditfield(app.DimentionsPanel, 'numeric');
            app.WidthxcmEditField.ValueChangedFcn = createCallbackFcn(app, @WidthxcmEditFieldValueChanged, true);
            app.WidthxcmEditField.Enable = 'off';
            app.HeightycmEditFieldLabel = uilabel(app.DimentionsPanel);
            app.HeightycmEditFieldLabel.Text = 'Height y [cm]';
            app.HeightycmEditField = uieditfield(app.DimentionsPanel, 'numeric');
            app.HeightycmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightycmEditFieldValueChanged, true);
            app.HeightycmEditField.Enable = 'off';
            app.ArenadimentioncmEditFieldLabel = uilabel(app.DimentionsPanel);
            app.ArenadimentioncmEditFieldLabel.Text = 'Arena dimention [cm]';
            app.ArenadimentioncmEditField = uieditfield(app.DimentionsPanel, 'numeric');
            app.ArenadimentioncmEditField.Limits = [0 10000];
            app.ArenadimentioncmEditField.Enable = 'off';
            app.ArenadimentioncmEditField.Value = 5;
            app.SavePanel = uipanel(app.UIFigure);
            app.SavePanel.Title = 'Save';
            app.SaveButton = uibutton(app.SavePanel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Enable = 'off';
            app.SaveButton.Text = 'Save';
            app.SaveemptyarenaButton = uibutton(app.SavePanel, 'push');
            app.SaveemptyarenaButton.ButtonPushedFcn = createCallbackFcn(app, @SaveemptyarenaButtonPushed, true);
            app.SaveemptyarenaButton.Enable = 'off';
            app.SaveemptyarenaButton.Text = 'Save empty arena';
            app.SaveasEditFieldLabel = uilabel(app.SavePanel);
            app.SaveasEditFieldLabel.Text = 'Save as:';
            app.SaveasEditField = uieditfield(app.SavePanel, 'text');
            app.SaveasEditField.ValueChangedFcn = createCallbackFcn(app, @SaveasEditFieldValueChanged, true);
            app.GeNEsISsaveLabel = uilabel(app.UIFigure);
            app.GeNEsISsaveLabel.Text = 'GeNEsIS - save';
            app.iButton = uibutton(app.UIFigure, 'push');
            app.iButton.ButtonPushedFcn = createCallbackFcn(app, @iButtonPushed, true);
            app.iButton.Text = 'i';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GeNEsIS_save

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end